classdef RadarDetectionofSmallBoatsatSea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRTMOG014_GUI_2018              matlab.ui.Figure
        RadarDetectionofSmallBoatsatSeaMATLABGUILabel  matlab.ui.control.Label
        MYMartinLabel                   matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        OSGOCFARDetectionTab            matlab.ui.container.Tab
        OSGO_Axes                       matlab.ui.control.UIAxes
        InputsandParametersPanel        matlab.ui.container.Panel
        NEditFieldLabel                 matlab.ui.control.Label
        N_OSGO                          matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        a_OSGO                          matlab.ui.control.NumericEditField
        NFFTEditFieldLabel              matlab.ui.control.Label
        NFFT_OSGO                       matlab.ui.control.NumericEditField
        CTEditFieldLabel                matlab.ui.control.Label
        CT_OSGO                         matlab.ui.control.NumericEditField
        MATfileLabel                    matlab.ui.control.Label
        MATfile_OSGO                    matlab.ui.control.EditField
        File_OSGO                       matlab.ui.control.Button
        Process_OSGO                    matlab.ui.control.StateButton
        CFARDropDownLabel               matlab.ui.control.Label
        CFARDropDown                    matlab.ui.control.DropDown
        EstimatePFAPanel                matlab.ui.container.Panel
        FromrangebinLabel               matlab.ui.control.Label
        RB1                             matlab.ui.control.NumericEditField
        TorangebinEditFieldLabel        matlab.ui.control.Label
        RB2                             matlab.ui.control.NumericEditField
        FromrangebinEditField_3Label    matlab.ui.control.Label
        RB3                             matlab.ui.control.NumericEditField
        TorangebinEditField_2Label      matlab.ui.control.Label
        RB4                             matlab.ui.control.NumericEditField
        ANDLabel                        matlab.ui.control.Label
        PFA                             matlab.ui.control.NumericEditField
        TorangebinEditField_2Label_2    matlab.ui.control.Label
        SelectrangebinswherenotargetispresenttoestimatePFALabel  matlab.ui.control.Label
        PlotSpectrogramTab              matlab.ui.container.Tab
        Spectrogram_Axes                matlab.ui.control.UIAxes
        InputsPanel                     matlab.ui.container.Panel
        RangebinEditFieldLabel          matlab.ui.control.Label
        RB_spectrogram                  matlab.ui.control.NumericEditField
        NFFTEditField_2Label            matlab.ui.control.Label
        NFFT_Spectrogram                matlab.ui.control.NumericEditField
        Process_Spectrogram             matlab.ui.control.StateButton
        File_Spectrogram                matlab.ui.control.Button
        MATfileLabel_4                  matlab.ui.control.Label
        MATfile_Spectrogram             matlab.ui.control.EditField
        PlotClutterPDFTab               matlab.ui.container.Tab
        PDF_Axes                        matlab.ui.control.UIAxes
        InputsPanel_2                   matlab.ui.container.Panel
        ANDLabel_2                      matlab.ui.control.Label
        FromrangebinLabel_2             matlab.ui.control.Label
        RB1_PDF                         matlab.ui.control.NumericEditField
        TorangebinEditFieldLabel_2      matlab.ui.control.Label
        RB2_PDF                         matlab.ui.control.NumericEditField
        FromrangebinEditField_3Label_2  matlab.ui.control.Label
        RB3_PDF                         matlab.ui.control.NumericEditField
        TorangebinEditField_2Label_3    matlab.ui.control.Label
        RB4_PDF                         matlab.ui.control.NumericEditField
        MATfileLabel_5                  matlab.ui.control.Label
        MATfile_PDF                     matlab.ui.control.EditField
        File_PDF                        matlab.ui.control.Button
        Process_PDF                     matlab.ui.control.StateButton
        SelectrangebinswherenotargetispresenttoestimatePFALabel_2  matlab.ui.control.Label
    end

    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)

            
        end

        % Value changed function: N_OSGO
        function N_OSGOValueChanged(app, event)
            
        end

        % Value changed function: a_OSGO
        function a_OSGOValueChanged(app, event)
            
        end

        % Value changed function: NFFT_OSGO
        function NFFT_OSGOValueChanged(app, event)
            
        end

        % Value changed function: CT_OSGO
        function CT_OSGOValueChanged(app, event)
            
        end

        % Callback function
        function ChoosefileButtonPushed(app, event)

        end

        % Value changed function: Process_OSGO
        function Process_OSGOPushed(app, event)
            N = app.N_OSGO.Value;
            a = app.a_OSGO.Value;
            NFFT = app.NFFT_OSGO.Value;
            CT = app.CT_OSGO.Value;
            RBa = app.RB1.Value;
            RBb = app.RB2.Value;
            RBc = app.RB3.Value;
            RBd = app.RB4.Value;
            chosenfile = app.MATfile_OSGO.Value;
            cfar = string(app.CFARDropDown.Value);
            file_exist = 0;
            
            %% OSGO-CFAR processing
            if exist(chosenfile, 'file')
                file_exist = 1;
            else
                warningMessage = sprintf('This file does not exist or is not in the correct format.');
                uiwait(msgbox(warningMessage));
            end
            
            if file_exist == 1
                load(chosenfile);	% input data with variables: Cdata, NumOfPRIs, NumOfRangeBins, PRI_s; must be included in .mat file

                % Parameters
                c0 = 299792458;                             % speed of light
                lambda = c0/(6.9*(10^9));                   % wavelength of the radar; f = 6.9 GHz
                bandwidth = 10^7;                           % pulse width = 100us; pure sinusoid at 6.9 GHz
                resolution_meters = c0/(2*bandwidth);       % range resolution in meters
                j = sqrt(-1);                               % sqrt(-1)
                
                % Sampling frequency, period
                PRI = PRI_s;
                PRF = 1/PRI;
                ts = 1/PRF;             % unused? Equal to PRI?
                
                % Spectrogram
                window_length = NFFT;    % length of window for FFT
                overlap = window_length/2;       % overlap in samples
                freq_axis = (-(NFFT/2):1:((NFFT/2) - 1))*PRF/NFFT;
                
                % OSGO-CFAR and OS-CFAR 'k' value
                k_OSGO = round(5*N/12);
                k_OS = round(3*N/4);
                
                % Determine kc; frequency-domain signal will be of size NFFT x kc
                X = Cdata(1:NumOfPRIs,1);
                kc = floor((length(X) - overlap)/(window_length - overlap));
                summation = zeros(kc,NumOfRangeBins);   % size of summation matrix
                
                % CFAR detection
                for RangeBin = 1:NumOfRangeBins
                    X = Cdata(1:NumOfPRIs,RangeBin);
                    [S,F,T1,P] = spectrogram(X,window_length,overlap,NFFT,PRF); 
                    kc = floor((length(X) - overlap)/(window_length - overlap));
                    signal = fftshift(P,1);             % centre zero-frequency component  
                    
                    % Matrix of background statistic
                    g = zeros(NFFT,kc);                 % NFFT x kc matrix
                
                    % CUT and reference cells created + shifted for each time instance
                    if cfar == 'OSGO-CFAR'
                        for CUT = ((N/2) + 1):1:(NFFT - (N/2))        
                            lagging_sorted = sort(signal(((CUT - N/2):(CUT - 1)),:));    % sort lagging window samples in ascending order
                            leading_sorted = sort(signal(((CUT + 1):(CUT + N/2)),:));    % sort leading window samples in ascending order
                            
                            OSGO_diff = leading_sorted(k_OSGO,:) - lagging_sorted(k_OSGO,:);
                            OSGO_lag_greater = find(OSGO_diff <= 0);
                            OSGO_lead_greater = find(OSGO_diff > 0); 
                            g(CUT,OSGO_lead_greater) = leading_sorted(k_OSGO,OSGO_lead_greater);
                            g(CUT,OSGO_lag_greater) = lagging_sorted(k_OSGO,OSGO_lag_greater);
                        end
                    elseif cfar == 'OS-CFAR'
                        for CUT = ((N/2) + 1):1:(NFFT - (N/2))        
                            window_sorted = sort([signal(((CUT + 1):(CUT + N/2)),:); signal(((CUT - N/2):(CUT - 1)),:)]); % sort window samples in ascending order
                            g(CUT,:) = window_sorted(k_OS,:);
                        end
                    end
                
                    % Extract the data of the detected elements
                    T = a.*g;                                   % CFAR detection threshold; NFFT x kc matrix
                    signal_minus_T = signal - T;                % NFFT x kc matrix
                    detections_index = find(signal_minus_T > 0);% returns indices (counting down columns) of values > 0
                    
                    detection_result = zeros(NFFT,kc);
                    detection_result(detections_index) = signal_minus_T(detections_index);  % same as signal_minus_T, but non-detections are all zero
                    summation(:,(RangeBin)) = fftshift(sum(detection_result,1),1);          % sum the columns; produce row; becomes column in summation
                    
                    % Print progress percentage
                    clc;
                    fprintf('Processing: %0.2f %% complete \n',RangeBin*100/NumOfRangeBins);
                end
                
                % Plot detections
                range_bins = 1:1:size(Cdata,2);
                time = (1:1:size(Cdata,1))*PRI;
                detections_index = find(summation > CT);              % max number of entries = kc x NumOfRangeBins 
                x = floor(detections_index./kc) + 1;                    % max x = NumOfRangeBins
                y = PRI*overlap*floor(mod(detections_index,kc));        % max mod is (kc - 1) therefore add 1; max y = kc*PRI*overlap = max time
                
                % Plot data in time vs range bin
                ax = app.OSGO_Axes;
                imagesc(ax,range_bins,time,20*log10(abs(Cdata)));
                hold(ax,'on')
                grid(ax,'on')
                plot(ax,x,y,'k.','MarkerSize',6);
                colorbar(ax);
                colormap(ax,jet);
                title(ax,'Detections in Measured Data');
                xlabel(ax,'Range Bin');
                ylabel(ax,'Time [seconds]');
                ax.XLim = [0.5 NumOfRangeBins];
                ax.YLim = [0 NumOfPRIs*PRI];
                
                % Calculate PFA for specific range bins
                if RBb > NumOfRangeBins
                    RBb = NumOfRangeBins;
                end
                if RBd > NumOfRangeBins
                    RBd = NumOfRangeBins;
                end
                if RBa > NumOfRangeBins
                    RBa = NumOfRangeBins;
                end
                if RBc > NumOfRangeBins
                    RBc = NumOfRangeBins;
                end
                
                first_RBs = RBa:RBb;
                second_RBs = RBc:RBd;
                no_overlap_RBs = union(first_RBs,second_RBs);
                
                PFA_calc = length(find(summation(:,no_overlap_RBs) > CT))/(length(no_overlap_RBs)*kc);
                app.PFA.Value = PFA_calc;
            end
            
            app.Process_OSGO.Value = false;    % change button appearance back to "clickable"

        end

        % Value changed function: MATfile_OSGO
        function MATfile_OSGOValueChanged(app, event)
            chosenfile = app.MATfile_OSGO.Value;

        end

        % Value changed function: RB1
        function RB1ValueChanged(app, event)
            value = app.RB1.Value;
            
        end

        % Size changed function: PlotSpectrogramTab
        function PlotSpectrogramTabSizeChanged(app, event)
            
            
        end

        % Value changed function: Process_Spectrogram
        function Process_SpectrogramValueChanged(app, event)
            RBs = app.RB_spectrogram.Value;
            NFFT2 = app.NFFT_Spectrogram.Value;
            chosenfile_s = app.MATfile_Spectrogram.Value;
            
            file_exist = 0;
            
            %% OSGO-CFAR processing
            if exist(chosenfile_s, 'file')
                file_exist = 1;
            else
                warningMessage = sprintf('This file does not exist or is not in the correct format.');
                uiwait(msgbox(warningMessage));
            end
            
            if file_exist == 1
                load(chosenfile_s);	% input data with variables: Cdata, NumOfPRIs, NumOfRangeBins, PRI_s; must be included in .mat file
                
                % Sampling frequency, period
                PRI = PRI_s;
                PRF = 1/PRI;
                
                % Spectrogram
                window_length = NFFT2;              % length of window for FFT
                overlap = window_length/2;          % overlap in samples
                freq_axis = (-(NFFT2/2):1:((NFFT2/2) - 1))*PRF/NFFT2;
                
                % Spectrogram signal
                if RBs > NumOfRangeBins
                    RBs = NumOfRangeBins;
                end
                RangeBin = RBs;
                X = Cdata(1:NumOfPRIs,RangeBin);
                [S,F,T1,P] = spectrogram(X,window_length,overlap,NFFT2,PRF); 
                kc = floor((length(X) - overlap)/(window_length - overlap));
                signal = fftshift(P,1);
          
                %% Plot spectrogram and detections
                ax2 = app.Spectrogram_Axes;
                imagesc(ax2,T1,freq_axis,10*log10(signal));
                set(ax2,'YDir','normal')    % correct orientation of image plot
                colorbar(ax2);
                colormap(ax2,jet);
                hold(ax2,'on')
                grid(ax2,'on')
                title(ax2,'Spectrogram of Measured Data');
                xlabel(ax2,'Time [seconds]');
                ylabel(ax2,'Frequency [Hz]');
                ax2.XLim = [0 NumOfPRIs*PRI];
                ax2.YLim = [-PRF/2 PRF/2];
            end
            
            app.Process_Spectrogram.Value = false;    % change button appearance back to "clickable"
            
        end

        % Value changed function: Process_PDF
        function Process_PDFValueChanged(app, event)
            RBa2 = app.RB1.Value;
            RBb2 = app.RB2.Value;
            RBc2 = app.RB3.Value;
            RBd2 = app.RB4.Value;
            chosenfile_pdf = app.MATfile_PDF.Value;
            
            file_exist = 0;
            
            %% OSGO-CFAR processing
            if exist(chosenfile_pdf, 'file')
                file_exist = 1;
            else
                warningMessage = sprintf('This file does not exist or is not in the correct format.');
                uiwait(msgbox(warningMessage));
            end
            
            if file_exist == 1
                load(chosenfile_pdf);	% input data with variables: Cdata, NumOfPRIs, NumOfRangeBins, PRI_s; must be included in .mat file
    
                if RBb2 > NumOfRangeBins
                    RBb2 = NumOfRangeBins;
                end
                if RBd2 > NumOfRangeBins
                    RBd2 = NumOfRangeBins;
                end
                if RBa2 > NumOfRangeBins
                    RBa2 = NumOfRangeBins;
                end
                if RBc2 > NumOfRangeBins
                    RBc2 = NumOfRangeBins;
                end
                
                first_RBs2 = RBa2:RBb2;
                second_RBs2 = RBc2:RBd2;
                no_overlap_RBs2 = union(first_RBs2,second_RBs2);
                
                Cdata2 = Cdata(:,no_overlap_RBs2);
                pdf_x = abs(Cdata2(:)).^2;	% create column vector from clutter data
                Ns = length(pdf_x);
                delta = 0.05;               % dimension of a range bin when computing the histogram
                yapl = 0:delta:max(pdf_x);  % number of range bins
                
                % Compute and plot histogram
                histo = hist(pdf_x,yapl)./(Ns*delta);
                ax3 = app.PDF_Axes;
                semilogy(ax3,yapl,histo,'.'); 
                grid(ax3,'on')
                title(ax3,'Clutter PDF After Square Law Detector');
                xlabel(ax3,'Return Strength');
                ylabel(ax3,'Logarithmic Probability Density');
                ax3.XLim = [0 Inf];
                ax3.YLim = [0 1];
            end
            
            app.Process_PDF.Value = false;    % change button appearance back to "clickable"
        end

        % Button pushed function: File_OSGO
        function File_OSGOPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.MRTMOG014_GUI_2018.Visible = 'off'; app.MRTMOG014_GUI_2018.Visible = 'on';
           
            app.MATfile_OSGO.Value = fullfile(path,file);
        end

        % Button pushed function: File_Spectrogram
        function File_SpectrogramButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.MRTMOG014_GUI_2018.Visible = 'off'; app.MRTMOG014_GUI_2018.Visible = 'on';
           
            app.MATfile_Spectrogram.Value = fullfile(path,file);
        end

        % Button pushed function: File_PDF
        function File_PDFButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            app.MRTMOG014_GUI_2018.Visible = 'off'; app.MRTMOG014_GUI_2018.Visible = 'on';
           
            app.MATfile_PDF.Value = fullfile(path,file);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRTMOG014_GUI_2018 = uifigure;
            app.MRTMOG014_GUI_2018.Name = 'UI Figure';
            app.RadarDetectionofSmallBoatsatSeaMATLABGUILabel = uilabel(app.MRTMOG014_GUI_2018);
            app.RadarDetectionofSmallBoatsatSeaMATLABGUILabel.Text = {'Radar Detection of Small Boats at Sea'; 'MATLAB GUI'; ''; ''};
            app.MYMartinLabel = uilabel(app.MRTMOG014_GUI_2018);
            app.MYMartinLabel.Text = 'M. Y. Martin';
            app.TabGroup = uitabgroup(app.MRTMOG014_GUI_2018);
            app.OSGOCFARDetectionTab = uitab(app.TabGroup);
            app.OSGOCFARDetectionTab.Title = 'OSGO-CFAR Detection';
            app.OSGO_Axes = uiaxes(app.OSGOCFARDetectionTab);
            title(app.OSGO_Axes, 'Detections in Measured Data')
            xlabel(app.OSGO_Axes, 'Range Bin')
            ylabel(app.OSGO_Axes, 'Time [seconds]')
            app.OSGO_Axes.XLim = [0 1];
            app.OSGO_Axes.YLim = [0 1];
            app.OSGO_Axes.YDir = 'reverse';
            app.InputsandParametersPanel = uipanel(app.OSGOCFARDetectionTab);
            app.InputsandParametersPanel.Title = 'Inputs and Parameters';
            app.NEditFieldLabel = uilabel(app.InputsandParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.N_OSGO = uieditfield(app.InputsandParametersPanel, 'numeric');
            app.N_OSGO.Limits = [2 1024];
            app.N_OSGO.RoundFractionalValues = 'on';
            app.N_OSGO.ValueChangedFcn = createCallbackFcn(app, @N_OSGOValueChanged, true);
            app.N_OSGO.Value = 16;
            app.EditFieldLabel = uilabel(app.InputsandParametersPanel);
            app.EditFieldLabel.Text = 'α';
            app.a_OSGO = uieditfield(app.InputsandParametersPanel, 'numeric');
            app.a_OSGO.Limits = [0 Inf];
            app.a_OSGO.ValueChangedFcn = createCallbackFcn(app, @a_OSGOValueChanged, true);
            app.a_OSGO.Value = 20;
            app.NFFTEditFieldLabel = uilabel(app.InputsandParametersPanel);
            app.NFFTEditFieldLabel.Text = 'NFFT';
            app.NFFT_OSGO = uieditfield(app.InputsandParametersPanel, 'numeric');
            app.NFFT_OSGO.Limits = [2 2048];
            app.NFFT_OSGO.RoundFractionalValues = 'on';
            app.NFFT_OSGO.ValueChangedFcn = createCallbackFcn(app, @NFFT_OSGOValueChanged, true);
            app.NFFT_OSGO.Value = 512;
            app.CTEditFieldLabel = uilabel(app.InputsandParametersPanel);
            app.CTEditFieldLabel.Text = 'CT';
            app.CT_OSGO = uieditfield(app.InputsandParametersPanel, 'numeric');
            app.CT_OSGO.ValueChangedFcn = createCallbackFcn(app, @CT_OSGOValueChanged, true);
            app.CT_OSGO.Value = 0.005;
            app.MATfileLabel = uilabel(app.InputsandParametersPanel);
            app.MATfileLabel.Text = 'MAT file';
            app.MATfile_OSGO = uieditfield(app.InputsandParametersPanel, 'text');
            app.MATfile_OSGO.ValueChangedFcn = createCallbackFcn(app, @MATfile_OSGOValueChanged, true);
            app.MATfile_OSGO.Editable = 'off';
            app.File_OSGO = uibutton(app.InputsandParametersPanel, 'push');
            app.File_OSGO.ButtonPushedFcn = createCallbackFcn(app, @File_OSGOPushed, true);
            app.File_OSGO.Text = 'Select MAT file';
            app.Process_OSGO = uibutton(app.InputsandParametersPanel, 'state');
            app.Process_OSGO.ValueChangedFcn = createCallbackFcn(app, @Process_OSGOPushed, true);
            app.Process_OSGO.Text = 'PROCESS';
            app.CFARDropDownLabel = uilabel(app.InputsandParametersPanel);
            app.CFARDropDownLabel.Text = 'CFAR';
            app.CFARDropDown = uidropdown(app.InputsandParametersPanel);
            app.CFARDropDown.Items = {'OSGO-CFAR', 'OS-CFAR'};
            app.CFARDropDown.Value = 'OSGO-CFAR';
            app.EstimatePFAPanel = uipanel(app.OSGOCFARDetectionTab);
            app.EstimatePFAPanel.Title = 'Estimate PFA';
            app.FromrangebinLabel = uilabel(app.EstimatePFAPanel);
            app.FromrangebinLabel.Text = 'From range bin*';
            app.RB1 = uieditfield(app.EstimatePFAPanel, 'numeric');
            app.RB1.Limits = [1 Inf];
            app.RB1.RoundFractionalValues = 'on';
            app.RB1.ValueChangedFcn = createCallbackFcn(app, @RB1ValueChanged, true);
            app.RB1.Value = 1;
            app.TorangebinEditFieldLabel = uilabel(app.EstimatePFAPanel);
            app.TorangebinEditFieldLabel.Text = 'To range bin';
            app.RB2 = uieditfield(app.EstimatePFAPanel, 'numeric');
            app.RB2.Limits = [1 Inf];
            app.RB2.RoundFractionalValues = 'on';
            app.RB2.Value = 15;
            app.FromrangebinEditField_3Label = uilabel(app.EstimatePFAPanel);
            app.FromrangebinEditField_3Label.Text = 'From range bin';
            app.RB3 = uieditfield(app.EstimatePFAPanel, 'numeric');
            app.RB3.Limits = [1 Inf];
            app.RB3.RoundFractionalValues = 'on';
            app.RB3.Value = 60;
            app.TorangebinEditField_2Label = uilabel(app.EstimatePFAPanel);
            app.TorangebinEditField_2Label.Text = 'To range bin';
            app.RB4 = uieditfield(app.EstimatePFAPanel, 'numeric');
            app.RB4.Limits = [1 Inf];
            app.RB4.RoundFractionalValues = 'on';
            app.RB4.Value = 96;
            app.ANDLabel = uilabel(app.EstimatePFAPanel);
            app.ANDLabel.Text = 'AND';
            app.PFA = uieditfield(app.EstimatePFAPanel, 'numeric');
            app.PFA.ValueDisplayFormat = '%5.2e';
            app.PFA.Editable = 'off';
            app.TorangebinEditField_2Label_2 = uilabel(app.EstimatePFAPanel);
            app.TorangebinEditField_2Label_2.Text = 'PFA';
            app.SelectrangebinswherenotargetispresenttoestimatePFALabel = uilabel(app.OSGOCFARDetectionTab);
            app.SelectrangebinswherenotargetispresenttoestimatePFALabel.Text = {'*Select range bins where no '; 'target is present to estimate a'; 'value for PFA'};
            app.PlotSpectrogramTab = uitab(app.TabGroup);
            app.PlotSpectrogramTab.SizeChangedFcn = createCallbackFcn(app, @PlotSpectrogramTabSizeChanged, true);
            app.PlotSpectrogramTab.Title = 'Plot Spectrogram';
            app.Spectrogram_Axes = uiaxes(app.PlotSpectrogramTab);
            title(app.Spectrogram_Axes, 'Spectrogram of Measured Data')
            xlabel(app.Spectrogram_Axes, 'Time [seconds]')
            ylabel(app.Spectrogram_Axes, 'Frequency [Hz]')
            app.Spectrogram_Axes.XLim = [0 1];
            app.Spectrogram_Axes.YLim = [0 1];
            app.InputsPanel = uipanel(app.PlotSpectrogramTab);
            app.InputsPanel.Title = 'Inputs';
            app.RangebinEditFieldLabel = uilabel(app.InputsPanel);
            app.RangebinEditFieldLabel.Text = 'Range bin';
            app.RB_spectrogram = uieditfield(app.InputsPanel, 'numeric');
            app.RB_spectrogram.Limits = [1 Inf];
            app.RB_spectrogram.RoundFractionalValues = 'on';
            app.RB_spectrogram.Value = 40;
            app.NFFTEditField_2Label = uilabel(app.InputsPanel);
            app.NFFTEditField_2Label.Text = 'NFFT';
            app.NFFT_Spectrogram = uieditfield(app.InputsPanel, 'numeric');
            app.NFFT_Spectrogram.Limits = [2 2048];
            app.NFFT_Spectrogram.RoundFractionalValues = 'on';
            app.NFFT_Spectrogram.Value = 512;
            app.Process_Spectrogram = uibutton(app.PlotSpectrogramTab, 'state');
            app.Process_Spectrogram.ValueChangedFcn = createCallbackFcn(app, @Process_SpectrogramValueChanged, true);
            app.Process_Spectrogram.Text = 'PROCESS';
            app.File_Spectrogram = uibutton(app.PlotSpectrogramTab, 'push');
            app.File_Spectrogram.ButtonPushedFcn = createCallbackFcn(app, @File_SpectrogramButtonPushed, true);
            app.File_Spectrogram.Text = 'Select MAT file';
            app.MATfileLabel_4 = uilabel(app.PlotSpectrogramTab);
            app.MATfileLabel_4.Text = 'MAT file';
            app.MATfile_Spectrogram = uieditfield(app.PlotSpectrogramTab, 'text');
            app.MATfile_Spectrogram.Editable = 'off';
            app.PlotClutterPDFTab = uitab(app.TabGroup);
            app.PlotClutterPDFTab.Title = 'Plot Clutter PDF';
            app.PDF_Axes = uiaxes(app.PlotClutterPDFTab);
            title(app.PDF_Axes, 'Clutter PDF After Square Law Detector')
            xlabel(app.PDF_Axes, 'Return Strength')
            ylabel(app.PDF_Axes, 'Logarithmic Probability Density')
            app.PDF_Axes.XLim = [0 1];
            app.PDF_Axes.YLim = [0 1];
            app.InputsPanel_2 = uipanel(app.PlotClutterPDFTab);
            app.InputsPanel_2.Title = 'Inputs';
            app.ANDLabel_2 = uilabel(app.InputsPanel_2);
            app.ANDLabel_2.Text = 'AND';
            app.FromrangebinLabel_2 = uilabel(app.InputsPanel_2);
            app.FromrangebinLabel_2.Text = 'From range bin*';
            app.RB1_PDF = uieditfield(app.InputsPanel_2, 'numeric');
            app.RB1_PDF.Limits = [1 Inf];
            app.RB1_PDF.RoundFractionalValues = 'on';
            app.RB1_PDF.Value = 1;
            app.TorangebinEditFieldLabel_2 = uilabel(app.InputsPanel_2);
            app.TorangebinEditFieldLabel_2.Text = 'To range bin';
            app.RB2_PDF = uieditfield(app.InputsPanel_2, 'numeric');
            app.RB2_PDF.Limits = [1 Inf];
            app.RB2_PDF.RoundFractionalValues = 'on';
            app.RB2_PDF.Value = 15;
            app.FromrangebinEditField_3Label_2 = uilabel(app.InputsPanel_2);
            app.FromrangebinEditField_3Label_2.Text = 'From range bin';
            app.RB3_PDF = uieditfield(app.InputsPanel_2, 'numeric');
            app.RB3_PDF.Limits = [1 Inf];
            app.RB3_PDF.RoundFractionalValues = 'on';
            app.RB3_PDF.Value = 60;
            app.TorangebinEditField_2Label_3 = uilabel(app.InputsPanel_2);
            app.TorangebinEditField_2Label_3.Text = 'To range bin';
            app.RB4_PDF = uieditfield(app.InputsPanel_2, 'numeric');
            app.RB4_PDF.Limits = [1 Inf];
            app.RB4_PDF.RoundFractionalValues = 'on';
            app.RB4_PDF.Value = 96;
            app.MATfileLabel_5 = uilabel(app.InputsPanel_2);
            app.MATfileLabel_5.Text = 'MAT file';
            app.MATfile_PDF = uieditfield(app.InputsPanel_2, 'text');
            app.MATfile_PDF.Editable = 'off';
            app.File_PDF = uibutton(app.InputsPanel_2, 'push');
            app.File_PDF.ButtonPushedFcn = createCallbackFcn(app, @File_PDFButtonPushed, true);
            app.File_PDF.Text = 'Select MAT file';
            app.Process_PDF = uibutton(app.InputsPanel_2, 'state');
            app.Process_PDF.ValueChangedFcn = createCallbackFcn(app, @Process_PDFValueChanged, true);
            app.Process_PDF.Text = 'PROCESS';
            app.SelectrangebinswherenotargetispresenttoestimatePFALabel_2 = uilabel(app.PlotClutterPDFTab);
            app.SelectrangebinswherenotargetispresenttoestimatePFALabel_2.Text = {'*Select range bins where no target is present to estimate a value'; 'for PFA'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadarDetectionofSmallBoatsatSea

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRTMOG014_GUI_2018)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRTMOG014_GUI_2018)
        end
    end
end